classdef NewApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        BrowseDICOMsMenu                matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        ExportandselectedseriesMenu     matlab.ui.container.Menu
        WithoutModificationMenu         matlab.ui.container.Menu
        WithModificationMenu            matlab.ui.container.Menu
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        SlideNumberSlider               matlab.ui.control.Slider
        SetasMainSeriesButton           matlab.ui.control.Button
        TheMainSeriesIsNotSelectedLampLabel  matlab.ui.control.Label
        TheMainSeriesIsNotSelectedLamp  matlab.ui.control.Lamp
        SliceLocationLampLabel          matlab.ui.control.Label
        SliceLocationLamp               matlab.ui.control.Lamp
        ImagePositionPatientLampLabel   matlab.ui.control.Label
        ImagePositionPatientLamp        matlab.ui.control.Lamp
        ImageOrientationLampLabel       matlab.ui.control.Label
        ImageOrientationLamp            matlab.ui.control.Lamp
        zAxisEditFieldLabel             matlab.ui.control.Label
        zspacingValue                   matlab.ui.control.NumericEditField
        mm                              matlab.ui.control.Label
        yaxisspacing                    matlab.ui.control.Label
        yspacingValue                   matlab.ui.control.NumericEditField
        mm2                             matlab.ui.control.Label
        xaxisSpacing                    matlab.ui.control.Label
        xspacingValue                   matlab.ui.control.NumericEditField
        mm3                             matlab.ui.control.Label
    end

    
    properties (Access = public)
        fS % Description
        directory % Description
        whole_series_images % Description
        whole_series_headers
        seriesNumsUnique
        seriesCounter
        idxSelectedSerie
        mainSeriesHeader
    end

    methods (Access = private)
    
        function results = reset(app)
            
        end
        
    end
    

    methods (Access = private)

        % Callback function
        function LoadButtonPushed(app, event)
            
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.SlideNumberSlider.Enable='on';    
            app.idxSelectedSerie = event.Indices;
            image = app.whole_series_images{1,app.seriesNumsUnique(app.idxSelectedSerie(1))};
            header = app.whole_series_headers{1,app.seriesNumsUnique(app.idxSelectedSerie(1))};
            postHeader = app.whole_series_headers{2,app.seriesNumsUnique(app.idxSelectedSerie(1))};
            if(app.seriesCounter(app.seriesNumsUnique(app.idxSelectedSerie(1)))>1)
                app.SlideNumberSlider.Limits=[1 app.seriesCounter(app.seriesNumsUnique(app.idxSelectedSerie(1)))];
            end
            app.SlideNumberSlider.Value = 1;
            imshow(image,[],'parent',app.UIAxes);
            if(isfield(header,'ImagePositionPatient'))
                if(header.ImagePositionPatient(3) ~= postHeader.ImagePositionPatient(3))
                   app.ImagePositionPatientLamp.Color = 'green';
                end
            else
                app.ImagePositionPatientLamp.Color = 'red';
            end
            if(isfield(header,'SliceLocation'))
                if(header.SliceLocation ~= postHeader.SliceLocation)
                   app.SliceLocationLamp.Color = 'green';
                   app.zspacingValue.Value = postHeader.SliceLocation-header.SliceLocation;
                end
            else
                app.SliceLocationLamp.Color = 'red';
                app.zspacingValue.Value = 0.0;
            end
            if(isfield(header,'ImageOrientationPatient'))
                app.ImageOrientationLamp.Color = 'green';
            else
                app.ImageOrientationLamp.Color = 'red';
            end
            if(isfield(header,'PixelSpacing'))
               app.yspacingValue.Value = header.PixelSpacing(1);
               app.xspacingValue.Value = header.PixelSpacing(2);
            end
                
            
        end

        % Menu selected function: BrowseDICOMsMenu
        function BrowseDICOMsMenuSelected(app, event)
            app.directory = uigetdir();
            app.LoadMenu.Enable='on';
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            fS=dicomreaddir(app.directory);
            [garb, nooffiles]=size(fS);
            if(nooffiles>0)
                seriesNums=zeros(garb,nooffiles);
                h = waitbar(0,'Sorting series in each pot...');
                for fileIdx=1:nooffiles
                    info = dicominfo(fS(fileIdx).name);
                    seriesNums(1,fileIdx)=info.SeriesNumber;
                    waitbar(fileIdx / nooffiles)
                end
                close(h)
                app.seriesNumsUnique = unique(seriesNums);
                app.whole_series_images = cell(500,200);
                app.whole_series_headers = cell(500,200);
                app.seriesCounter = ones(1,200);
                L = waitbar(0,'Inserting each serie into a cell array...');
                for fileIdx=1:nooffiles
                    info = dicominfo(fS(fileIdx).name);
                    app.whole_series_images{app.seriesCounter(info.SeriesNumber),...
                        info.SeriesNumber} = dicomread(fS(fileIdx).name);
                    app.whole_series_headers{app.seriesCounter(info.SeriesNumber),...
                        info.SeriesNumber} = dicominfo(fS(fileIdx).name);
                    app.seriesCounter(info.SeriesNumber)=...
                        app.seriesCounter(info.SeriesNumber)+1;
                    waitbar(fileIdx/nooffiles)
                end
                close(L);
                for kk=1:size(app.seriesNumsUnique,2)
                    header = app.whole_series_headers{1,app.seriesNumsUnique(kk)};
                    image = app.whole_series_images{1,app.seriesNumsUnique(kk)};
                    
                    tableDicoms{kk,1}=header.SeriesDescription;
                    tableDicoms{kk,2}=app.seriesCounter(app.seriesNumsUnique(kk))-1;
                    tableDicoms{kk,3}=false;
                end
                app.UITable.Data = tableDicoms;
                app.UITable.ColumnEditable = true;
            else
                f = warndlg('There is not any DICOM file in this directory!');
                uiwait
            end
        end

        % Value changing function: SlideNumberSlider
        function SlideNumberSliderValueChanging(app, event)
                changingValue = event.Value;
                discreteValue = uint8(changingValue);
                image = app.whole_series_images{discreteValue,...
                    app.seriesNumsUnique(app.idxSelectedSerie(1))};
                imshow(image,[],'parent',app.UIAxes);
        end

        % Button pushed function: SetasMainSeriesButton
        function SetasMainSeriesButtonPushed(app, event)
            for ii=1:app.seriesCounter(app.seriesNumsUnique(app.idxSelectedSerie(1)))-1
                app.mainSeriesHeader{ii,1} = app.whole_series_headers{ii,app.idxSelectedSerie(1)};
            end
            if(size(app.mainSeriesHeader,1)>2)
                app.TheMainSeriesIsNotSelectedLampLabel.Text = 'The Main Series Is Selected';
                app.TheMainSeriesIsNotSelectedLamp.Color = 'green';
            end
            
        end

        % Menu selected function: ExportandselectedseriesMenu
        function ExprtandselectedseriesMenuSelected(app, event)
            
        end

        % Menu selected function: WithoutModificationMenu
        function WithoutModificationMenuSelected(app, event)
            exportDirectory = uigetdir();
            seriesNumImages = app.seriesCounter(app.seriesNumsUnique(app.idxSelectedSerie(1)))-1
            for ii=1:seriesNumImages
                selectedSeriesHeader = app.whole_series_headers{ii,app.seriesNumsUnique(app.idxSelectedSerie(1))};
                selectedSeriesImage = app.whole_series_images{ii,app.seriesNumsUnique(app.idxSelectedSerie(1))};
                dicomwrite(selectedSeriesImage,[exportDirectory '\IMG_exported_' num2str(ii) '.dcm'],selectedSeriesHeader);
            end
            
        end

        % Menu selected function: WithModificationMenu
        function WithModificationMenuSelected(app, event)
            exportDirectory = uigetdir();
            seriesNumImages = app.seriesCounter(app.seriesNumsUnique(app.idxSelectedSerie(1)))-1
            studyFakeUID = dicomuid;
            seriesFakeUID = dicomuid;
            for ii=1:seriesNumImages
                selectedSeriesHeader = app.whole_series_headers{ii,app.seriesNumsUnique(app.idxSelectedSerie(1))};
                selectedSeriesImage = app.whole_series_images{ii,app.seriesNumsUnique(app.idxSelectedSerie(1))};
                interfaceHeader = app.mainSeriesHeader{ii,1};
                interfaceHeader.Width = selectedSeriesHeader.Width;
                interfaceHeader.Height = selectedSeriesHeader.Height;
                interfaceHeader.BitDepth = selectedSeriesHeader.BitDepth;
                interfaceHeader.ColorType = selectedSeriesHeader.ColorType;
                interfaceHeader.StudyInstanceUID = studyFakeUID;
                interfaceHeader.SeriesInstanceUID = seriesFakeUID;
                interfaceHeader.PhotometricInterpretation = selectedSeriesHeader.PhotometricInterpretation;
                dicomwrite(selectedSeriesImage,[exportDirectory '\IMG_exported_' num2str(ii) '.dcm'],interfaceHeader);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.BrowseDICOMsMenu = uimenu(app.FileMenu);
            app.BrowseDICOMsMenu.MenuSelectedFcn = createCallbackFcn(app, @BrowseDICOMsMenuSelected, true);
            app.BrowseDICOMsMenu.Text = 'Browse DICOMs';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Enable = 'off';
            app.LoadMenu.Text = 'Load';
            app.ExportandselectedseriesMenu = uimenu(app.FileMenu);
            app.ExportandselectedseriesMenu.MenuSelectedFcn = createCallbackFcn(app, @ExprtandselectedseriesMenuSelected, true);
            app.ExportandselectedseriesMenu.Text = 'Export and selected series';
            app.WithoutModificationMenu = uimenu(app.ExportandselectedseriesMenu);
            app.WithoutModificationMenu.MenuSelectedFcn = createCallbackFcn(app, @WithoutModificationMenuSelected, true);
            app.WithoutModificationMenu.Text = 'Without Modification';
            app.WithModificationMenu = uimenu(app.ExportandselectedseriesMenu);
            app.WithModificationMenu.MenuSelectedFcn = createCallbackFcn(app, @WithModificationMenuSelected, true);
            app.WithModificationMenu.Text = 'With Modification';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Series Description'; 'Number of Images'; 'Select'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Visible = 'off';
            app.SlideNumberSlider = uislider(app.UIFigure);
            app.SlideNumberSlider.Limits = [1 100];
            app.SlideNumberSlider.MajorTicks = [];
            app.SlideNumberSlider.MajorTickLabels = {};
            app.SlideNumberSlider.ValueChangingFcn = createCallbackFcn(app, @SlideNumberSliderValueChanging, true);
            app.SlideNumberSlider.MinorTicks = [];
            app.SlideNumberSlider.Enable = 'off';
            app.SlideNumberSlider.Value = 1;
            app.SetasMainSeriesButton = uibutton(app.UIFigure, 'push');
            app.SetasMainSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @SetasMainSeriesButtonPushed, true);
            app.SetasMainSeriesButton.Text = 'Set as Main Series';
            app.TheMainSeriesIsNotSelectedLampLabel = uilabel(app.UIFigure);
            app.TheMainSeriesIsNotSelectedLampLabel.Text = 'The Main Series Is Not Selected';
            app.TheMainSeriesIsNotSelectedLamp = uilamp(app.UIFigure);
            app.SliceLocationLampLabel = uilabel(app.UIFigure);
            app.SliceLocationLampLabel.Text = 'Slice Location';
            app.SliceLocationLamp = uilamp(app.UIFigure);
            app.ImagePositionPatientLampLabel = uilabel(app.UIFigure);
            app.ImagePositionPatientLampLabel.Text = 'Image Position Patient';
            app.ImagePositionPatientLamp = uilamp(app.UIFigure);
            app.ImageOrientationLampLabel = uilabel(app.UIFigure);
            app.ImageOrientationLampLabel.Text = 'Image Orientation';
            app.ImageOrientationLamp = uilamp(app.UIFigure);
            app.zAxisEditFieldLabel = uilabel(app.UIFigure);
            app.zAxisEditFieldLabel.Text = 'z Axis';
            app.zspacingValue = uieditfield(app.UIFigure, 'numeric');
            app.mm = uilabel(app.UIFigure);
            app.mm.Text = 'mm';
            app.yaxisspacing = uilabel(app.UIFigure);
            app.yaxisspacing.Text = 'y Axis';
            app.yspacingValue = uieditfield(app.UIFigure, 'numeric');
            app.mm2 = uilabel(app.UIFigure);
            app.mm2.Text = 'mm';
            app.xaxisSpacing = uilabel(app.UIFigure);
            app.xaxisSpacing.Text = 'x Axis';
            app.xspacingValue = uieditfield(app.UIFigure, 'numeric');
            app.mm3 = uilabel(app.UIFigure);
            app.mm3.Text = 'mm';
        end
    end

    methods (Access = public)

        % Construct app
        function app = NewApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end